classdef CSAGraphs_v2_1_New_labels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GCaMPgraphsUIFigure         matlab.ui.Figure
        ROIPanel                    matlab.ui.container.Panel
        ROIUITable                  matlab.ui.control.Table
        OutputPanel                 matlab.ui.container.Panel
        SaveEmfCheckBox             matlab.ui.control.CheckBox
        SavePdfCheckBox             matlab.ui.control.CheckBox
        SavePngCheckBox             matlab.ui.control.CheckBox
        SaveResolutionDpiDropDown   matlab.ui.control.DropDown
        ResolutiondpiDropDownLabel  matlab.ui.control.Label
        SaveImagesButton            matlab.ui.control.Button
        PlotcontrolsPanel           matlab.ui.container.Panel
        PlotTreatmentDropDown       matlab.ui.control.DropDown
        TreatmentLabel              matlab.ui.control.Label
        PlotMethodDropDown          matlab.ui.control.DropDown
        PlotmethodLabel             matlab.ui.control.Label
        PlotLabelFontSizeDropDown   matlab.ui.control.DropDown
        LabelFontSize               matlab.ui.control.Label
        PlotXAxisIncLabel           matlab.ui.control.Label
        PlotXAxisIncEditField       matlab.ui.control.NumericEditField
        PlotMarkerFillCheckBox      matlab.ui.control.CheckBox
        PlotMarkerSizeDropDown      matlab.ui.control.DropDown
        MarkerSizeLabel             matlab.ui.control.Label
        PlotSpikesCheckBox          matlab.ui.control.CheckBox
        PlotROIMethodDropDown       matlab.ui.control.DropDown
        ROImethodLabel              matlab.ui.control.Label
        PlotRoiLimitEditField       matlab.ui.control.NumericEditField
        ROIsLabel                   matlab.ui.control.Label
        PlotTimeIncLabel            matlab.ui.control.Label
        PlotTimeIncEditField        matlab.ui.control.NumericEditField
        PlotFontSizeDropDown        matlab.ui.control.DropDown
        PlotFontSizeLabel           matlab.ui.control.Label
        PlotTimeMaxEditField        matlab.ui.control.NumericEditField
        PlotTimeLabel               matlab.ui.control.Label
        PlotTimeMinEditField        matlab.ui.control.NumericEditField
        PlotMaximumLabel            matlab.ui.control.Label
        PlotMinimumLabel            matlab.ui.control.Label
        PlotRatioLabel              matlab.ui.control.Label
        PlotIntensityLabel          matlab.ui.control.Label
        PlotRatioMinEditField       matlab.ui.control.NumericEditField
        PlotRatioMaxEditField       matlab.ui.control.NumericEditField
        PlotIntMaxEditField         matlab.ui.control.NumericEditField
        PlotIntMinEditField         matlab.ui.control.NumericEditField
        PlotButton                  matlab.ui.control.Button
        TreatmentsPanel             matlab.ui.container.Panel
        TreatmentNoGapCheckBox      matlab.ui.control.CheckBox
        TreatmentLineColorLabel     matlab.ui.control.Label
        TreatmentFontColorLabel     matlab.ui.control.Label
        TreatmentLineColourButton   matlab.ui.control.Button
        TreatmentFontColourButton   matlab.ui.control.Button
        TreatmentModeDropDown       matlab.ui.control.DropDown
        DisplayLabel                matlab.ui.control.Label
        TreatmentFontSizeDropDown   matlab.ui.control.DropDown
        FontSizeDropDownLabel       matlab.ui.control.Label
        TreatmentUpdateButton       matlab.ui.control.Button
        TreatmentRemoveButton       matlab.ui.control.Button
        TreatmentAddButton          matlab.ui.control.Button
        TreatmentLoadButton         matlab.ui.control.Button
        TreatmentUITable            matlab.ui.control.Table
        AxesPanel                   matlab.ui.container.Panel
        SelectDataPanel             matlab.ui.container.Panel
        LowerPlotDropDown           matlab.ui.control.DropDown
        LowerplotLabel              matlab.ui.control.Label
        UpperPlotDropDown           matlab.ui.control.DropDown
        UpperplotDropDownLabel      matlab.ui.control.Label
        SelectFilesPanel            matlab.ui.container.Panel
        FileLoadButton              matlab.ui.control.Button
        FilesListBox                matlab.ui.control.ListBox
        FilesListBoxLabel           matlab.ui.control.Label
        DirectoryLabel              matlab.ui.control.Label
        DirectoryButton             matlab.ui.control.Button
    end


    properties (Access = private)
        var % structure of variables
    end

    methods (Access = private)

        function funcGetFiles(app)
            % get the directory structure
            dirStruct = dir(app.var.dirIn);
            % strip out any directories
            fileIdx = ~[dirStruct.isdir];
            % sort the filenames in order
            [sortedNames,~] = sortrows({dirStruct(fileIdx).name}');
            % select only files with xlsx extension
            includeIdx = contains(sortedNames,'.xlsx');
            % display the contents of the directory in the input listbox
            files = sortedNames(includeIdx);
            app.FilesListBox.Items = files;
        end

        function funcGetSheets(app)
            app.var.fileIn = app.FilesListBox.Value;
            app.var.worksheets = sheetnames(app.var.fileIn);
            app.UpperPlotDropDown.Items = app.var.worksheets;
            app.LowerPlotDropDown.Items = app.var.worksheets;
            if ~isempty(find(ismember(app.var.worksheets,'spikes'), 1))
                app.UpperPlotDropDown.Value = app.var.worksheets(find(ismember(app.var.worksheets,'spikes')));
            end
            if ~isempty(find(ismember(app.var.worksheets,'resting'), 1))
                app.LowerPlotDropDown.Value = app.var.worksheets(find(ismember(app.var.worksheets,'resting')));
            end
            app.var.data{1}.metric = app.UpperPlotDropDown.Value;
            app.var.data{1}.values = funcGetData(app,app.var.data{1}.metric);
            app.var.data{2}.metric = app.LowerPlotDropDown.Value;
            app.var.data{2}.values = funcGetData(app,app.var.data{2}.metric);
            [app.var.nT, app.var.nROI] = size(app.var.data{1}.values);
            % correct nROI for time column
            app.var.nROI = app.var.nROI-1;
            app.PlotTimeMaxEditField.Value = app.var.nT;
            app.PlotRoiLimitEditField.Value = app.var.nROI;
            % get the data
            app.var.baseline = funcGetData(app,'baseline');
            app.var.resting = funcGetData(app,'resting');
            % get the spike summary data
            app.var.spikeSummary = funcGetData(app,'spike summary');
            G = groupcounts(app.var.spikeSummary,{'treatment'});
            app.PlotTreatmentDropDown.Items = [{'All'}; G{:,'treatment'}];
            % get the object stats
            app.var.objectStats = funcGetData(app,'object stats');
            % update the ROI table
            funcRoiTableUpdate(app)
        end

        function data = funcGetData(app,worksheet)
            data = readtable(app.var.fileIn,'FileType','spreadsheet',"ReadVariableNames",true,"ReadRowNames",false,"Sheet",worksheet);
        end

        function funcPlotData(app)
            Imin = app.PlotIntMinEditField.Value;
            Imax = app.PlotIntMaxEditField.Value;
            Rmin = app.PlotRatioMinEditField.Value;
            Rmax = app.PlotRatioMaxEditField.Value;
            Tmin = app.PlotTimeMinEditField.Value;
            Tmax = app.PlotTimeMaxEditField.Value;
            Tinc = app.PlotTimeIncEditField.Value;
            %             nROI = app.PlotRoiLimitEditField.Value;
            %             nROI = min(nROI,app.var.nROI);
            %             ROImethod = app.PlotROIMethodDropDown.Value;
            markerSize = str2double(app.PlotMarkerSizeDropDown.Value);

            % Create plots.
                    switch app.PlotMethodDropDown.Value
                        case 'single figure'
                            t = tiledlayout(app.AxesPanel,2,1,'TileSpacing','none','Padding','compact'); % Requires R2019b or later
                        case 'dual figure'
                            t = tiledlayout(app.AxesPanel,2,1,'TileSpacing','compact','Padding','compact'); % Requires R2019b or later
                    end
                    app.var.hax{1} = nexttile(t);
                    app.var.hax{2} = nexttile(t);
                    
                    for i = 1:2
                        if ~isempty(app.var.data{i}.values)
                            ax = app.var.hax{i};
                            hold(ax,'off')
                            funcRoiTableUpdate(app);
                            idx = 1+find(app.var.ROIidx);
                    if diff(app.var.data{i}.values{1:2,1}) == 1
                        plot(ax,app.var.data{i}.values{Tmin:Tmax,1}*Tinc,app.var.data{i}.values{Tmin:Tmax,idx})
                    else
                        plot(ax,app.var.data{i}.values{Tmin:Tmax,1},app.var.data{i}.values{Tmin:Tmax,idx})
                    end
                    hold(ax,'on')
                    % add spike annotation
                    if  app.PlotSpikesCheckBox.Value && strcmp(app.var.data{i}.metric,'spikes')
                        loc = (app.var.spikeSummary.frame - 1)*Tinc;
                        if isfield(app.var.spikeSummary, 'fold_increase')
                            hgt = app.var.spikeSummary.fold_increase.*(1+markerSize/200);
                        else
                            hgt = app.var.spikeSummary.foldIncrease.*(1+markerSize/200);
                        end
                        % plot the peak markers
                        Sidx = ismember(app.var.spikeSummary.ROI,idx-1) & ismember(app.var.spikeSummary.frame,Tmin:Tmax);
                        if app.PlotMarkerFillCheckBox.Value
                            plot(ax,loc(Sidx),hgt(Sidx),'kv','MarkerSize',markerSize,'MarkerFaceColor','k')
                        else
                            plot(ax,loc(Sidx),hgt(Sidx),'kv','MarkerSize',markerSize)
                        end

                    end

                    % set the Y axis scaling
                    offset = 1.05 + str2double(app.TreatmentFontSizeDropDown.Value)/100;
                    switch app.var.data{i}.metric
                        case {'raw';'filtered';'subtracted';'baseline'}
                            ylabel(ax,'Intensity');
                            %ylim(ax(i),'auto')
                            ylim(ax,[Imin*0.8 Imax*offset])
                        case {'F/F_{b}';'spikes';'resting'}
                            ylim(ax,[Rmin*0.8 Rmax*offset])
                            str = app.var.data{i}.metric;
                            ylabel(app.var.hax{1}, 'Spikes (Fold Change)');
                            app.var.hax{1}.YLabel.FontSize = str2double(app.PlotLabelFontSizeDropDown.Value);
                            ylabel(app.var.hax{2}, 'Non-spike Fluorescence ');
                            app.var.hax{2}.YLabel.FontSize = str2double(app.PlotLabelFontSizeDropDown.Value);
                        case '\deltaF/F_{b}'
                            ylim(ax,[Rmin*0.8-1 Rmax*offset-1])
                            ylabel(ax,'Ratio')
                        case 'normalised'
                            ylim(ax,[0 offset])
                            ylabel(ax,'Normalised')
                        case 'viability'
                            ylabel(ax,'Cell death');
                            ylim(ax,'auto')
                    end
                    % set the axis labels
                    title(ax,[])
                    xlim(ax,[0 Tmax*Tinc])
                    xinc = app.PlotXAxisIncEditField.Value;
                    xticks(ax,[0:xinc:xinc*ceil(Tmax*Tinc/xinc)])
                    switch app.PlotMethodDropDown.Value
                        case 'single figure'
                            if i == 2
                                xticklabels(ax,[0:xinc:xinc*ceil(Tmax*Tinc/xinc)])
                            else
                                xticklabels(ax,[])
                            end
                        case 'dual figure'
                            xticklabels(ax,[0:xinc:xinc*ceil(Tmax*Tinc/xinc)])
                            xlabel(ax, 'Time (s)','FontSize',str2double(app.PlotLabelFontSizeDropDown.Value));
                    end
                    ax.FontSize = str2double(app.PlotFontSizeDropDown.Value);
                    ax.YLabel.FontSize = str2double(app.PlotLabelFontSizeDropDown.Value);
                end
            end
            % Link the axes
            linkaxes([app.var.hax{1},app.var.hax{2}],'x');
            switch app.PlotMethodDropDown.Value
                case 'single figure'
                    t.Title.String = [];
                    t.YLabel.String = [];
                    t.XLabel.String = 'Time (s)';
                    t.XLabel.FontSize = str2double(app.PlotLabelFontSizeDropDown.Value);
                case 'dual figure'
            end
        end

        function funcGetParameters(app)
            [~,rootName,~] = fileparts(app.var.fileIn);
            app.var.dirParam = strrep(app.var.dirIn,'results','parameters');
            fileName = [app.var.dirParam filesep rootName '_param.mat'];
            if isfile(fileName)
                app.var.param = load(fileName);
                if isfield(app.var.param.experiment,'treatments')
                    cols = cell(size(app.var.param.experiment.treatments,1),1);
                    app.TreatmentUITable.Data = [app.var.param.experiment.treatments cols];
                    removeStyle(app.TreatmentUITable)
                    % set a default shading colour for treatments
                    s = uistyle('BackgroundColor',[1 1 1]);
                    addStyle(app.TreatmentUITable,s,'column',4)
                    idx = find(ismember(app.var.param.experiment.treatments(:,1),'treatment'));
                    s = uistyle('BackgroundColor',[0.8 0.8 0.8]);
                    addStyle(app.TreatmentUITable,s,'cell',[idx repmat(4,length(idx),1)])
                    % replace terms with standardised terms
                    data = app.TreatmentUITable.Data;
                    data(:,1) = strrep(data(:,1),'control','Baseline');
                    data(:,1) = strrep(data(:,1),'treatment','Treatment');
                    data(:,1) = strrep(data(:,1),'washout','Washout/Baseline');
                    app.TreatmentUITable.Data = data;
                end
            end
        end

        function funcPlotTreatments(app)
            if ~isempty(app.var.param.experiment.treatments)
                fontSize = str2double(app.TreatmentFontSizeDropDown.Value);
                Tmin = app.PlotTimeMinEditField.Value;
                Tmax = app.PlotTimeMaxEditField.Value;
                Tinc = app.PlotTimeIncEditField.Value;
                %treatments = app.var.param.experiment.treatments
                treatments = app.TreatmentUITable.Data;
                % check all treatments are in range
                idx = [treatments{:,2}]>Tmax;
                treatments(idx,:) = [];
                idx = [treatments{:,3}]>Tmax;
                [treatments{idx,3}]=Tmax;
                idx = [treatments{:,3}]<Tmin;
                treatments(idx,:) = [];
                if app.TreatmentNoGapCheckBox.Value
                    treatments(2:end,2) = num2cell([treatments{2:end,2}]-0.5);
                    treatments(1:end-1,3) = num2cell([treatments{1:end-1,3}]+0.5);
                end
                %idx = [treatments{:,2}]<Tmin;
                %[treatments{idx,2}]=Tmin;
                % set the shading colours
                app.var.TreatmentShadingColor = repmat([0 0 0], size(treatments,1),1);
                styles = funcGetStyle(app);
                app.var.TreatmentShadingColor(styles(:,1),:) = styles(:,3:5);
                app.var.TreatmentShadingColor = reshape(app.var.TreatmentShadingColor,1,[],3);
                for iP = 1:2
                    ax = app.var.hax{iP};
                    hold(ax,'on')
                    limits = ylim(ax);
                    h = findobj(ax,'Tag','treatment');
                    delete(h);
                    offset = (limits(2)-limits(1)).*fontSize/100;
                    % plot dotted verticals
                    xV = cat(1,treatments{:,2:3})';
                    xV = ([xV;xV]-1)*Tinc;
                    yV = repmat([limits(1) limits(2)]',1,size(xV,2));
                    plot(ax,xV,yV,'Color',app.var.TreatmentLineColor,'Linestyle',':','Tag','treatment');
                    % plot treatment bars
                    xH = cell2mat(treatments(:,2:3))';
                    xH = [xH;flipud(xH)];
                    % shift times as start at zero
                    xH = (xH-1)*Tinc;
                    yH = [repmat(limits(2)-offset,size(xH,2),2)'; repmat(limits(2),size(xH,2),2)'];
                    c = ones(size(treatments,1),1);
                    switch app.TreatmentModeDropDown.Value
                        case 'text'
                            patch(ax,xH,yH,c,'FaceColor','none','EdgeColor',app.var.TreatmentLineColor,'Tag','treatment')
                            text(ax,mean(xH,1),mean(yH,1),treatments(:,1),'HorizontalAlignment','center','VerticalAlignment','middle','FontSize',fontSize,'Color',app.var.TreatmentFontColor,'Interpreter','tex','Tag','treatment');
                        case 'shading'
                            patch(ax,xH,yH,c,'CData',app.var.TreatmentShadingColor,'Tag','treatment')
                        case 'text and shading'
                            patch(ax,xH,yH,c,'CData',app.var.TreatmentShadingColor,'Tag','treatment')
                            text(ax,mean(xH,1),mean(yH,1),treatments(:,1),'HorizontalAlignment','center','VerticalAlignment','middle','FontSize',fontSize,'Color',app.var.TreatmentFontColor,'Interpreter','tex','Tag','treatment');
                    end
                    if isfield(app,'spike_freq') && iP == 2
                        units = repmat(' cell^{-1} min^{-1}',size(xH,2),1);
                        freq = num2str(app.spike_freq',3);
                        text(ax,mean(xH,1),mean(yH,1)-offset,freq,'HorizontalAlignment','center','VerticalAlignment','bottom','FontSize',fontSize,'Tag','treatment','Interpreter','tex');
                        text(ax,mean(xH,1),mean(yH,1)-offset.*1.7,units,'HorizontalAlignment','center','VerticalAlignment','bottom','FontUnits','pixels','FontSize',fontSize,'Tag','treatment','Interpreter','tex');

                    end
                end
            end
        end

        function colours = funcGetStyle(app)
            n = size(app.TreatmentUITable.Data,1);
            colours = [(1:n)'  repmat([4 1 1 1],n,1)];
            s = app.TreatmentUITable.StyleConfigurations;
            idx = ismember(s.Target,'cell');
            s(~idx,:) = [];
            if any(idx)
                temp = [cat(1,s.TargetIndex{:}) cat(1,s.Style.BackgroundColor)];
                idx = temp(:,2) ~= 4;
                temp(idx,:) = [];
                colours(temp(:,1),:) = temp;
            end
        end

        function funcRoiTableUpdate(app)
            Tmin = app.PlotTimeMinEditField.Value;
            Tmax = app.PlotTimeMaxEditField.Value;
            nROI = app.PlotRoiLimitEditField.Value;
            nROI = min(nROI,app.var.nROI);
            ROImethod = app.PlotROIMethodDropDown.Value;
            treatment = app.PlotTreatmentDropDown.Value;
            app.var.ROIidx = false(app.var.nROI,1);
            switch ROImethod
                case 'All'
                    idx = 1:app.var.nROI;
                case 'First'
                    idx = 1:nROI;
                case 'Random'
                    idx = randsample(app.var.nROI,nROI);
                case 'Brightest'
                    [~,s] = sort(app.var.baseline{Tmin+1,2:end},'descend');
                    idx = s(1:nROI);
                case 'Average'
                    V = mean(app.var.baseline{Tmin:Tmax,2:end},1);
                    [~,s] = sort(V,'descend');
                    idx = s(1:nROI);
                case 'Minimum'
                    V = min(app.var.baseline{Tmin:Tmax,2:end},[],1);
                    [~,s] = sort(V,'descend');
                    idx = s(1:nROI);
                case 'Selected'
                    data = app.ROIUITable.Data;
                    idx = cell2mat(data(:,2));
                case 'Original'
                    idx = find(app.var.objectStats{:,'Use'})';
                case 'Largest'
                    [~,s] = sort(app.var.objectStats{:,'Area'},'descend');
                    idx = s(1:nROI);
                case 'Smallest'
                    [~,s] = sort(app.var.objectStats{:,'Area'},'ascend');
                    idx = s(1:nROI);
                case 'Most Eccentric'
                    [~,s] = sort(app.var.objectStats{:,'Eccentricity'},'descend');
                    idx = s(1:nROI);
                case 'Least Eccentric'
                    [~,s] = sort(app.var.objectStats{:,'Eccentricity'},'ascend');
                    idx = s(1:nROI);
                case 'Most Spikes'
                    data = app.ROIUITable.Data;
                    T = sortrows(data,4,'descend');
                    idx = cell2mat(T(1:nROI,1));
                case 'Least Spikes'
                    data = app.ROIUITable.Data;
                    T = sortrows(data,4,'ascend');
                    idx = cell2mat(T(1:nROI,1));
                case 'High Transient'
                    data = app.ROIUITable.Data;
                    T = sortrows(data,5,'descend');
                    idx = cell2mat(T(1:nROI,1));                    
                case 'Low Transient'
                    data = app.ROIUITable.Data;
                    T = sortrows(data,5,'ascend');
                    idx = cell2mat(T(1:nROI,1));                    
                case 'Sorted'
                    data = app.ROIUITable.Data;
                    idx = cell2mat(data(1:nROI,2));
                otherwise
                    idx = 1:app.var.nROI;
            end
            app.var.ROIidx(idx) = 1;
            spikes = groupcounts(app.var.spikeSummary,{'ROI'});
            transients = max(app.var.resting{:,2:end});
            areas = app.var.objectStats{:,'Area'};
            data = [num2cell(uint8(1:app.var.nROI)'),num2cell(logical(app.var.ROIidx)), num2cell(areas), num2cell(uint8(spikes{:,'GroupCount'})), num2cell(transients')];
            app.ROIUITable.Data = data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.var.dirIn = pwd;
            app.DirectoryLabel.Text = app.var.dirIn;
            funcGetFiles(app)
            app.var.TreatmentFontColor = [0 0 0];
            app.TreatmentFontColorLabel.BackgroundColor = app.var.TreatmentFontColor;
            app.var.TreatmentLineColor = [0 0 0];
            app.TreatmentLineColorLabel.BackgroundColor = app.var.TreatmentLineColor;
            % position the GUI in the middle of the screen
            r = groot;
            s = r.ScreenSize;
            p = app.GCaMPgraphsUIFigure.Position;
            app.GCaMPgraphsUIFigure.Position = [round((s(3)-p(3))/2) round((s(4)-p(4))/2) p(3) p(4)];
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            app.var.dirIn = uigetdir;
            cd(app.var.dirIn)
            app.DirectoryLabel.Text = app.var.dirIn;
            funcGetFiles(app)
            figure(app.GCaMPgraphsUIFigure)
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)

        end

        % Button pushed function: FileLoadButton
        function FileLoadButtonPushed(app, event)
            funcGetSheets(app)
            funcGetParameters(app)
            funcPlotData(app)
            funcPlotTreatments(app)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            funcPlotData(app)
            funcPlotTreatments(app)
        end

        % Value changed function: UpperPlotDropDown
        function UpperPlotDropDownValueChanged(app, event)
            app.var.data{1}.metric = app.UpperPlotDropDown.Value;
            app.var.data{1}.values = funcGetData(app,app.var.data{1}.metric);
            funcPlotData(app)
        end

        % Value changed function: LowerPlotDropDown
        function LowerPlotDropDownValueChanged(app, event)
            app.var.data{2}.metric = app.LowerPlotDropDown.Value;
            app.var.data{2}.values = funcGetData(app,app.var.data{2}.metric);
            funcPlotData(app)
        end

        % Button pushed function: TreatmentLoadButton
        function TreatmentLoadButtonPushed(app, event)
            funcGetParameters(app)
        end

        % Button pushed function: TreatmentAddButton
        function TreatmentAddButtonPushed(app, event)
            % get the current treatments
            data = app.TreatmentUITable.Data;
            % add a blank row
            data = [data;{'not set',0,0}];
            % update the table
            app.TreatmentUITable.Data = data;
        end

        % Button pushed function: TreatmentRemoveButton
        function TreatmentRemoveButtonPushed(app, event)
            % see if anything has been selected
            if isfield(app.var,'TreatmentIdx')
                % get the current treatments
                data = app.TreatmentUITable.Data;
                % delete the selected row
                data(app.var.TreatmentIdx,:) = [];
                % update the table
                if isempty(data)
                    % add a blank row
                    data = {'not set',0,0};
                end
                % update the table
                app.TreatmentUITable.Data = data;
            end
        end

        % Cell selection callback: TreatmentUITable
        function TreatmentUITableCellSelection(app, event)
            if ~isempty(event.Indices)
                app.var.TreatmentIdx = event.Indices(1);
                if event.Indices(2) == 4
                    % now get the desired colour
                    c = uisetcolor([0.5 0.5 0.5],'Shading colour');
                    s = uistyle('BackgroundColor',c);
                    addStyle(app.TreatmentUITable,s,'cell',event.Indices)
                    figure(app.GCaMPgraphsUIFigure)
                end
            end
        end

        % Button pushed function: TreatmentUpdateButton
        function TreatmentUpdateButtonPushed(app, event)
            app.var.treatments = app.TreatmentUITable.Data;
            % find any rows that have not been set
            idx = ismember(app.var.treatments(:,1),{'not set'});
            % delete these rows
            app.var.treatments(idx,:) = [];
            app.TreatmentUITable.Data = app.var.treatments;
        end

        % Callback function
        function TreatmentDisplayButtonPushed(app, event)
            %funcPlotTreatments(app)
        end

        % Button pushed function: TreatmentFontColourButton
        function TreatmentFontColourButtonPushed(app, event)
            app.var.TreatmentFontColor = uisetcolor([1 0 0],'Font colour');
            app.TreatmentFontColorLabel.BackgroundColor = app.var.TreatmentFontColor;
            figure(app.GCaMPgraphsUIFigure)
        end

        % Button pushed function: TreatmentLineColourButton
        function TreatmentLineColourButtonPushed(app, event)
            app.var.TreatmentLineColor = uisetcolor([0 0 0],'Line colour');
            app.TreatmentLineColorLabel.BackgroundColor = app.var.TreatmentLineColor;
            figure(app.GCaMPgraphsUIFigure)
        end

        % Value changed function: PlotTimeMaxEditField
        function PlotTimeMaxEditFieldValueChanged(app, event)
            if app.PlotTimeMaxEditField.Value > app.var.nT
                app.PlotTimeMaxEditField.Value = app.var.nT;
            end
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
            [~,core,~] = fileparts(app.var.fileIn);
            if app.SavePngCheckBox.Value
                [file,path] = uiputfile({'*.png'},'Save image',[core '_plot.png']);
                exportgraphics(app.AxesPanel,fullfile(path,file),'Resolution',str2double(app.SaveResolutionDpiDropDown.Value))
                figure(app.GCaMPgraphsUIFigure)
            end
            if app.SavePdfCheckBox.Value
                [file,path] = uiputfile({'*.pdf'},'Save image',[core '_plot.pdf']);
                exportgraphics(app.AxesPanel,fullfile(path,file))
                figure(app.GCaMPgraphsUIFigure)
            end
            if app.SaveEmfCheckBox.Value
                [file,path] = uiputfile({'*.emf'},'Save image',[core '_plot.emf']);
                exportgraphics(app.AxesPanel,fullfile(path,file))
                figure(app.GCaMPgraphsUIFigure)
            end
        end

        % Cell edit callback: ROIUITable
        function ROIUITableCellEdit(app, event)
            if ~isempty(event.Indices)
                app.PlotROIMethodDropDown.Value = 'Selected';
                data = app.ROIUITable.Data;
                app.var.ROIidx = cell2mat(data(:,2));
                funcPlotData(app)
            end
        end

        % Value changed function: PlotROIMethodDropDown
        function PlotROIMethodDropDownValueChanged(app, event)
            %idx = funcRoiTableUpdate(app);
            funcPlotData(app)
            funcPlotTreatments(app)
        end

        % Value changed function: PlotRoiLimitEditField
        function PlotRoiLimitEditFieldValueChanged(app, event)
            %value = app.PlotRoiLimitEditField.Value;
            %idx = funcRoiTableUpdate(app);
            funcPlotData(app)
            funcPlotTreatments(app)
        end

        % Value changed function: PlotTreatmentDropDown
        function PlotTreatmentDropDownValueChanged(app, event)
            value = app.PlotTreatmentDropDown.Value;
            spikes = groupcounts(app.var.spikeSummary,{'ROI'});
            transients = max(app.var.resting{:,2:end});
            areas = app.var.objectStats{:,'Area'};
            data = [num2cell(uint8(1:app.var.nROI)'),num2cell(logical(app.var.ROIidx)), num2cell(areas), num2cell(uint8(spikes{:,'GroupCount'})), num2cell(transients')];
            app.ROIUITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GCaMPgraphsUIFigure = uifigure('Visible', 'off');
            colormap(app.GCaMPgraphsUIFigure, 'gray');
            app.GCaMPgraphsUIFigure.Name = 'GCaMPgraphs';
            app.SelectFilesPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.SelectFilesPanel.Title = 'Select files';
            app.DirectoryButton = uibutton(app.SelectFilesPanel, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.DirectoryLabel = uilabel(app.SelectFilesPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.FilesListBoxLabel = uilabel(app.SelectFilesPanel);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.SelectFilesPanel);
            app.FilesListBox.Items = {'none selected'};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = 'none selected';
            app.FileLoadButton = uibutton(app.SelectFilesPanel, 'push');
            app.FileLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FileLoadButtonPushed, true);
            app.FileLoadButton.Text = 'File Load';
            app.SelectDataPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.SelectDataPanel.Title = 'Select data';
            app.UpperplotDropDownLabel = uilabel(app.SelectDataPanel);
            app.UpperplotDropDownLabel.Text = 'Upper plot';
            app.UpperPlotDropDown = uidropdown(app.SelectDataPanel);
            app.UpperPlotDropDown.Items = {'none'};
            app.UpperPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @UpperPlotDropDownValueChanged, true);
            app.UpperPlotDropDown.Value = 'none';
            app.LowerplotLabel = uilabel(app.SelectDataPanel);
            app.LowerplotLabel.Text = 'Lower plot';
            app.LowerPlotDropDown = uidropdown(app.SelectDataPanel);
            app.LowerPlotDropDown.Items = {'none'};
            app.LowerPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @LowerPlotDropDownValueChanged, true);
            app.LowerPlotDropDown.Value = 'none';
            app.AxesPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.TreatmentsPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.TreatmentsPanel.Title = 'Treatments';
            app.TreatmentUITable = uitable(app.TreatmentsPanel);
            app.TreatmentUITable.ColumnName = {'Treatment'; 'Start'; 'End'; 'Colour'};
            app.TreatmentUITable.RowName = {};
            app.TreatmentUITable.ColumnEditable = [true true true false];
            app.TreatmentUITable.CellSelectionCallback = createCallbackFcn(app, @TreatmentUITableCellSelection, true);
            app.TreatmentLoadButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentLoadButtonPushed, true);
            app.TreatmentLoadButton.Text = 'Load';
            app.TreatmentAddButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentAddButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentAddButtonPushed, true);
            app.TreatmentAddButton.Text = 'Add';
            app.TreatmentRemoveButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentRemoveButtonPushed, true);
            app.TreatmentRemoveButton.Text = 'Remove';
            app.TreatmentUpdateButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentUpdateButtonPushed, true);
            app.TreatmentUpdateButton.Text = 'Update';
            app.FontSizeDropDownLabel = uilabel(app.TreatmentsPanel);
            app.FontSizeDropDownLabel.Text = 'Font Size';
            app.TreatmentFontSizeDropDown = uidropdown(app.TreatmentsPanel);
            app.TreatmentFontSizeDropDown.Items = {'6', '7', '8', '9', '10', '11', '12', '14', '16', '18', '20', '24', '28', '32'};
            app.TreatmentFontSizeDropDown.Value = '14';
            app.DisplayLabel = uilabel(app.TreatmentsPanel);
            app.DisplayLabel.Text = 'Display';
            app.TreatmentModeDropDown = uidropdown(app.TreatmentsPanel);
            app.TreatmentModeDropDown.Items = {'text', 'shading', 'text and shading'};
            app.TreatmentModeDropDown.Value = 'text and shading';
            app.TreatmentFontColourButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentFontColourButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentFontColourButtonPushed, true);
            app.TreatmentFontColourButton.Text = 'Font Colour';
            app.TreatmentLineColourButton = uibutton(app.TreatmentsPanel, 'push');
            app.TreatmentLineColourButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentLineColourButtonPushed, true);
            app.TreatmentLineColourButton.Text = 'Line Colour';
            app.TreatmentFontColorLabel = uilabel(app.TreatmentsPanel);
            app.TreatmentFontColorLabel.Text = '';
            app.TreatmentLineColorLabel = uilabel(app.TreatmentsPanel);
            app.TreatmentLineColorLabel.Text = '';
            app.TreatmentNoGapCheckBox = uicheckbox(app.TreatmentsPanel);
            app.TreatmentNoGapCheckBox.Text = 'No Gap';
            app.TreatmentNoGapCheckBox.Value = true;
            app.PlotcontrolsPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.PlotcontrolsPanel.Title = 'Plot controls';
            app.PlotButton = uibutton(app.PlotcontrolsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotIntMinEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotIntMaxEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotIntMaxEditField.Value = 1;
            app.PlotRatioMaxEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotRatioMaxEditField.Value = 3;
            app.PlotRatioMinEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotRatioMinEditField.Value = 0.8;
            app.PlotIntensityLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotIntensityLabel.Text = 'Intensity';
            app.PlotRatioLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotRatioLabel.Text = 'Ratio';
            app.PlotMinimumLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotMinimumLabel.Text = 'minimum';
            app.PlotMaximumLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotMaximumLabel.Text = 'maximum';
            app.PlotTimeMinEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotTimeMinEditField.Limits = [1 Inf];
            app.PlotTimeMinEditField.RoundFractionalValues = 'on';
            app.PlotTimeMinEditField.ValueDisplayFormat = '%.0f';
            app.PlotTimeMinEditField.Value = 1;
            app.PlotTimeLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotTimeLabel.Text = 'Frame';
            app.PlotTimeMaxEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotTimeMaxEditField.Limits = [1 Inf];
            app.PlotTimeMaxEditField.RoundFractionalValues = 'on';
            app.PlotTimeMaxEditField.ValueDisplayFormat = '%.0f';
            app.PlotTimeMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PlotTimeMaxEditFieldValueChanged, true);
            app.PlotTimeMaxEditField.Value = 1;
            app.PlotFontSizeLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotFontSizeLabel.Text = 'Plot Font Size';
            app.PlotFontSizeDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotFontSizeDropDown.Items = {'6', '7', '8', '9', '10', '11', '12', '14', '16', '18', '20', '24', '28', '32'};
            app.PlotFontSizeDropDown.Value = '12';
            app.PlotTimeIncEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotTimeIncEditField.Limits = [0 Inf];
            app.PlotTimeIncEditField.RoundFractionalValues = 'on';
            app.PlotTimeIncEditField.ValueDisplayFormat = '%.0f';
            app.PlotTimeIncEditField.Value = 5;
            app.PlotTimeIncLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotTimeIncLabel.Text = 'Time interval (s)';
            app.ROIsLabel = uilabel(app.PlotcontrolsPanel);
            app.ROIsLabel.Text = '# ROIs';
            app.PlotRoiLimitEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotRoiLimitEditField.Limits = [1 Inf];
            app.PlotRoiLimitEditField.RoundFractionalValues = 'on';
            app.PlotRoiLimitEditField.ValueDisplayFormat = '%.0f';
            app.PlotRoiLimitEditField.ValueChangedFcn = createCallbackFcn(app, @PlotRoiLimitEditFieldValueChanged, true);
            app.PlotRoiLimitEditField.Value = 1;
            app.ROImethodLabel = uilabel(app.PlotcontrolsPanel);
            app.ROImethodLabel.Text = 'ROI method';
            app.PlotROIMethodDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotROIMethodDropDown.Items = {'All', 'Original', 'Selected', 'Random', 'First', 'Brightest', 'Average', 'Minimum', 'Largest', 'Smallest', 'Most Eccentric', 'Least Eccentric', 'Most Spikes', 'Least Spikes', 'High Transient', 'Low Transient', 'Sorted'};
            app.PlotROIMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotROIMethodDropDownValueChanged, true);
            app.PlotROIMethodDropDown.Value = 'All';
            app.PlotSpikesCheckBox = uicheckbox(app.PlotcontrolsPanel);
            app.PlotSpikesCheckBox.Text = 'Mark spikes';
            app.PlotSpikesCheckBox.Value = true;
            app.MarkerSizeLabel = uilabel(app.PlotcontrolsPanel);
            app.MarkerSizeLabel.Text = 'Marker Size';
            app.PlotMarkerSizeDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotMarkerSizeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.PlotMarkerSizeDropDown.Value = '4';
            app.PlotMarkerFillCheckBox = uicheckbox(app.PlotcontrolsPanel);
            app.PlotMarkerFillCheckBox.Text = 'Marker Fill';
            app.PlotMarkerFillCheckBox.Value = true;
            app.PlotXAxisIncEditField = uieditfield(app.PlotcontrolsPanel, 'numeric');
            app.PlotXAxisIncEditField.Limits = [0 Inf];
            app.PlotXAxisIncEditField.RoundFractionalValues = 'on';
            app.PlotXAxisIncEditField.ValueDisplayFormat = '%.0f';
            app.PlotXAxisIncEditField.Value = 60;
            app.PlotXAxisIncLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotXAxisIncLabel.Text = ' X axis ticks (s)';
            app.LabelFontSize = uilabel(app.PlotcontrolsPanel);
            app.LabelFontSize.Text = 'Label Font Size';
            app.PlotLabelFontSizeDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotLabelFontSizeDropDown.Items = {'6', '7', '8', '9', '10', '11', '12', '14', '16', '18', '20', '24', '28', '32'};
            app.PlotLabelFontSizeDropDown.Value = '14';
            app.PlotmethodLabel = uilabel(app.PlotcontrolsPanel);
            app.PlotmethodLabel.Text = 'Plot method';
            app.PlotMethodDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotMethodDropDown.Items = {'single figure', 'dual figure'};
            app.PlotMethodDropDown.Value = 'single figure';
            app.TreatmentLabel = uilabel(app.PlotcontrolsPanel);
            app.TreatmentLabel.Text = 'Treatment';
            app.PlotTreatmentDropDown = uidropdown(app.PlotcontrolsPanel);
            app.PlotTreatmentDropDown.Items = {'All'};
            app.PlotTreatmentDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTreatmentDropDownValueChanged, true);
            app.PlotTreatmentDropDown.Value = 'All';
            app.OutputPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.OutputPanel.Title = 'Output';
            app.SaveImagesButton = uibutton(app.OutputPanel, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = 'Save';
            app.ResolutiondpiDropDownLabel = uilabel(app.OutputPanel);
            app.ResolutiondpiDropDownLabel.Text = 'Resolution (dpi)';
            app.SaveResolutionDpiDropDown = uidropdown(app.OutputPanel);
            app.SaveResolutionDpiDropDown.Items = {'72', '150', '300', '600'};
            app.SaveResolutionDpiDropDown.Value = '300';
            app.SavePngCheckBox = uicheckbox(app.OutputPanel);
            app.SavePngCheckBox.Text = '*.png';
            app.SavePngCheckBox.Value = true;
            app.SavePdfCheckBox = uicheckbox(app.OutputPanel);
            app.SavePdfCheckBox.Text = '*.pdf';
            app.SaveEmfCheckBox = uicheckbox(app.OutputPanel);
            app.SaveEmfCheckBox.Text = '*.emf';
            app.ROIPanel = uipanel(app.GCaMPgraphsUIFigure);
            app.ROIPanel.Title = 'ROI';
            app.ROIUITable = uitable(app.ROIPanel);
            app.ROIUITable.ColumnName = {'ID'; 'use'; 'area'; 'spikes'; 'base'};
            app.ROIUITable.RowName = {};
            app.ROIUITable.ColumnSortable = true;
            app.ROIUITable.ColumnEditable = [false true false false false];
            app.ROIUITable.CellEditCallback = createCallbackFcn(app, @ROIUITableCellEdit, true);
            % Show the figure after all components are created
            app.GCaMPgraphsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSAGraphs_v2_1_New_labels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GCaMPgraphsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GCaMPgraphsUIFigure)
        end
    end
end